classdef JointTuneViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        fileList               matlab.ui.control.ListBox
        combIm                 matlab.ui.control.UIAxes
        Panel_2                matlab.ui.container.Panel
        normalizeButton        matlab.ui.control.StateButton
        combImLinesButton      matlab.ui.control.StateButton
        clim                   matlab.ui.control.Slider
        ElectrodeSpinnerLabel  matlab.ui.control.Label
        electrodeSpinner       matlab.ui.control.Spinner
        ChannelEditFieldLabel  matlab.ui.control.Label
        channelLabel           matlab.ui.control.NumericEditField
        SFSpinnerLabel         matlab.ui.control.Label
        sfSpinner              matlab.ui.control.Spinner
        DIRSpinnerLabel        matlab.ui.control.Label
        oriSpinner             matlab.ui.control.Spinner
        cpdLabel               matlab.ui.control.Label
        degLabel               matlab.ui.control.Label
        UnitsDropDownLabel     matlab.ui.control.Label
        unitsDropDown          matlab.ui.control.DropDown
        TabGroup2              matlab.ui.container.TabGroup
        AllelecsTab            matlab.ui.container.Tab
        oriMargIm              matlab.ui.control.UIAxes
        sfMargIm               matlab.ui.control.UIAxes
        SingleelecTab          matlab.ui.container.Tab
        singIm                 matlab.ui.control.UIAxes
        singSf                 matlab.ui.control.UIAxes
        singOri                matlab.ui.control.UIAxes
        refreshFileListButton  matlab.ui.control.Button
    end


    properties (Access = private)
        chanMapFile = '/u/vnl/matlab/open-ephys/ChanMap_A32';
        dataDir = '/users/stimulus/data/lin-array/joint-tuning';
        oepCodeDir
        curFile
        curElec = 1;
        curChan
        chanMap
        nOri = 24;
        nSf = 14;
        nElec = 32;
        curSf = 6;
        curOri = 10;
        S
        sfLabel
        oriLabel
        combImClim = [-1,1];
        rLimElec = nan(2,32);
        colors = struct('blue',[0,0,1],'lightBlue',[0.75,0.75,1],'gray',0.6*[1,1,1],'imLine',[0.9,0.7,0.7]);
        isNormalized = 0;
        combImDoLines = 1;
        units = struct('countSub',1,'powSub',2,'powNorm',3);
        curUnit = 1;
    end

    methods (Access = private)
    
        function LoadData(app)
            temp = load([app.dataDir,'/',app.curFile]);
            app.S = temp.S;
            
            app.rLimElec(1,:) = reshape(min(min(app.S.countSub)),[1,app.nElec]);
            app.rLimElec(2,:) = reshape(max(max(app.S.countSub)),[1,app.nElec]);
            
            app.sfLabel = cellfun(@num2str,num2cell(app.S.sfVals),'UniformOutput',false);
            for iS = 1:app.nSf
                if numel(app.sfLabel{iS})>4
                    app.sfLabel{iS} = app.sfLabel{iS}(1:4);
                end
            end
            app.oriLabel = cellfun(@num2str,num2cell(app.S.oriVals),'UniformOutput',false);
            for iO = 1:app.nOri
                for i = 1:(4-numel(app.oriLabel{iO}))
                    app.oriLabel{iO} = ['',app.oriLabel{iO}];
                end
            end
        end
        
        
        function UpdateCombIm(app)
            
            if app.curUnit==app.units.countSub
                X = app.S.countSub(:,:,app.chanMap);
            elseif app.curUnit==app.units.powSub
                X = app.S.powSub(:,:,app.chanMap);
            elseif app.curUnit==app.units.powNorm
                X = app.S.powNorm(:,:,app.chanMap);
            end
            X = X./max(abs(X(:)));
            if app.isNormalized
                X = bsxfun(@rdivide,X,max(max(abs(X))));
            end
            A = [];
            for i = 1
                Ai = [];
                for j = 1:32
%                     k = 16*(i-1)+j;
                    k = j;
                    if k==app.curElec
                        c = 1;
                    else
                        c = -1;
                    end
                    bar = c*ones(app.nOri+2,1);
%                     bar(1+app.curOri) = 0.7;
                    x = [bar, [c*ones(1,app.nSf);X(:,:,k);c*ones(1,app.nSf)], bar];
                    Ai = [Ai,x];
                end
                A = [A;Ai];
            end
            imagesc(app.combIm,A)
            colormap(app.combIm,'gray')
            set(app.combIm,'clim',app.combImClim)
            axis(app.combIm,'image','off','xy')
            
            if app.combImDoLines
                hold(app.combIm,'on')
                plot(app.combIm,1:size(A,2),1+app.curOri*ones(1,size(A,2)),'-','color',app.colors.imLine)
            end
        end
        
        function UpdateMarginalIms(app)
            if app.curUnit==app.units.countSub
                X = app.S.countSub(:,:,app.chanMap);
            elseif app.curUnit==app.units.powSub
                X = app.S.powSub(:,:,app.chanMap);
            elseif app.curUnit==app.units.powNorm
                X = app.S.powNorm(:,:,app.chanMap);
            end
            
            Xo = reshape(mean(X,2),[app.nOri,app.nElec]);
            Xo = Xo./max(Xo(:));
            if app.isNormalized
                Xo = bsxfun(@rdivide,Xo,max(Xo));
            end
            x = app.S.oriVals;
            imagesc(app.oriMargIm,x,1:app.nElec,Xo.')
            colormap(app.oriMargIm,'gray')
            set(app.oriMargIm,'clim',[-1,1])
%             axis(app.oriMargIm,'off')
            
            set(app.oriMargIm,'xtick',x)
            set(app.oriMargIm,'xticklabel',app.oriLabel)
            set(app.oriMargIm,'xTickLabelRotation',60)
%             set(app.oriMargIm,'xlim',[0,360])

            Xs = reshape(mean(X,1),[app.nSf,app.nElec]);
            Xs = Xs./max(Xs(:));
            if app.isNormalized
                Xs = bsxfun(@rdivide,Xs,max(Xs));
            end
            x = log10(app.S.sfVals(2:end));
            imagesc(app.sfMargIm,x,1:app.nElec,Xs(2:end,:).')
            colormap(app.sfMargIm,'gray')
            set(app.sfMargIm,'clim',[-1,1])
%             axis(app.oriMargIm,'off')
            
            set(app.sfMargIm,'xtick',x)
            set(app.sfMargIm,'xticklabel',app.sfLabel(2:end))
            set(app.sfMargIm,'xTickLabelRotation',60)
%             set(app.oriMargIm,'xlim',[0,360])
            
        end
        
        
        function UpdateSingIm(app)
            X = app.S.countSub(:,:,app.curChan);
            X = X./max(abs(X(:)));
            cla(app.singIm);
            imagesc(app.singIm,1:app.nSf,1:app.nOri,X)
            hold(app.singIm,'on')
            %plot(app.singIm,app.curSf,app.curOri,'r.','markersize',3)
            colormap(app.singIm,'gray')
            set(app.singIm,'clim',[-1,1])
            axis(app.singIm,'image','xy')
            set(app.singIm,'xtick',1:app.nSf)
            set(app.singIm,'xticklabel',app.sfLabel)
            set(app.singIm,'xTickLabelRotation',60)
            set(app.singIm,'ytick',1:app.nOri)
            set(app.singIm,'yticklabel',app.oriLabel)
            if app.combImDoLines
                hold(app.singIm,'on')
                plot(app.singIm,[0.5,app.nSf+0.5],app.curOri*[1,1],'-','color',app.colors.imLine)
                plot(app.singIm,app.curSf*[1,1],[0.5,app.nOri+0.5],'-','color',app.colors.imLine)
            end
        end
        
        function UpdateSingSf(app)
            x = log10(app.S.sfVals(2:end))';
            e0 = app.S.sponCountSem(app.curChan)*ones(1,app.nSf-1);
            y = app.S.countSub(app.curOri,2:end,app.curChan);
            e = app.S.countSem(app.curOri,2:end,app.curChan);

            cla(app.singSf)
            hold(app.singSf,'on')
            plot(app.singSf,x,-e0,'-','color',app.colors.gray)
            plot(app.singSf,x,e0,'-','color',app.colors.gray)
            plot(app.singSf,x,y,'-','color',app.colors.blue)
            plot(app.singSf,x,y-e,'-','color',app.colors.lightBlue)
            plot(app.singSf,x,y+e,'-','color',app.colors.lightBlue)
            
            ylim(app.singSf,[app.rLimElec(1,app.curChan),app.rLimElec(2,app.curChan)])
            set(app.singSf,'xtick',x)
            set(app.singSf,'xticklabel',app.sfLabel(2:end))
            set(app.singSf,'xTickLabelRotation',60)
            get(app.singSf)
        end
        
        function UpdateSingOri(app)
            x = app.S.oriVals;
            e0 = app.S.sponCountSem(app.curChan)*ones(1,app.nOri);
            y = app.S.countSub(:,app.curSf,app.curChan);
            e = app.S.countSem(:,app.curSf,app.curChan);

            cla(app.singOri)
            hold(app.singOri,'on')
            plot(app.singOri,x,-e0,'-','color',app.colors.gray)
            plot(app.singOri,x,e0,'-','color',app.colors.gray)
            plot(app.singOri,x,y,'-','color',app.colors.blue)
            plot(app.singOri,x,y-e,'-','color',app.colors.lightBlue)
            plot(app.singOri,x,y+e,'-','color',app.colors.lightBlue)
            
            ylim(app.singOri,[app.rLimElec(1,app.curChan),app.rLimElec(2,app.curChan)])
            set(app.singOri,'xtick',x)
            set(app.singOri,'xticklabel',app.oriLabel)
            set(app.singOri,'xTickLabelRotation',60)
            get(app.singOri)
        end
 
        function UpdateAll(app)
            app.UpdateCombIm;
            app.UpdateSingIm;
            app.UpdateSingOri;
            app.UpdateSingSf;
        end
        
        function UpdateFileList(app)
            D = dir([app.dataDir,'/*OriSf*']);
            set(app.fileList,'Items',{D.name})
            if numel(D)>0
                app.curFile = D(1).name;
                app.LoadData;
            
                app.sfSpinner.Value = app.curSf;
                app.cpdLabel.Text = [num2str(app.S.sfVals(app.curSf)),' cpd'];
                app.oriSpinner.Value = app.curOri;
                app.degLabel.Text = [num2str(app.S.oriVals(app.curOri)),' deg'];
                
                app.UpdateCombIm;
                app.UpdateMarginalIms;
                app.UpdateSingIm;
                app.UpdateSingSf;
                app.UpdateSingOri;
            end
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            clc
            temp = load(app.chanMapFile);
            app.chanMap = temp.chanMap;
            app.curChan = app.chanMap(app.curElec);
            app.channelLabel.Value = app.curChan;
            app.UpdateFileList;
            
            set(app.UIFigure,'position',[137         134        2350        1224])
        end

        % Value changed function: fileList
        function newFileSelected(app, event)
            app.curFile = app.fileList.Value;
            app.LoadData;
            app.UpdateCombIm;
            app.UpdateMarginalIms;
            app.UpdateSingIm;
            app.UpdateSingSf;
            app.UpdateSingOri;
        end

        % Value changed function: electrodeSpinner
        function newElecSelected(app, event)
            value = app.electrodeSpinner.Value;
            app.curElec = value;
            app.curChan = app.chanMap(app.curElec);
            app.channelLabel.Value = app.curChan;
            app.UpdateCombIm;
            app.UpdateSingIm;
            app.UpdateSingSf;
            app.UpdateSingOri;
        end

        % Value changed function: sfSpinner
        function newSfSelected(app, event)
            value = app.sfSpinner.Value;
            app.curSf = value;
            app.cpdLabel.Text = [num2str(app.S.sfVals(app.curSf)),' cpd'];
            app.UpdateSingIm;
            app.UpdateSingOri;
        end

        % Value changed function: oriSpinner
        function newOriSelected(app, event)
            value = app.oriSpinner.Value;
            app.curOri = value;
            app.degLabel.Text = [num2str(app.S.oriVals(app.curOri)),' deg'];
            app.UpdateCombIm;
            app.UpdateSingIm;
            app.UpdateSingSf;
        end

        % Value changing function: clim
        function climSliderChanging(app, event)
            changingValue = event.Value;
            app.combImClim = changingValue*[-1,1];
            set(app.combIm,'clim',changingValue*[-1,1])
        end

        % Callback function: combImLinesButton, normalizeButton
        function normalizeButtonPressed(app, event)
            value = app.normalizeButton.Value;
            app.isNormalized = value;
            app.UpdateCombIm;
            app.UpdateMarginalIms;
        end

        % Value changed function: combImLinesButton
        function combImLinesButtonPressed(app, event)
            value = app.combImLinesButton.Value;
            app.combImDoLines = value;
            app.UpdateCombIm;
            app.UpdateSingIm;
        end

        % Value changed function: unitsDropDown
        function newUnitsSelected(app, event)
            app.curUnit = find(strcmp(app.unitsDropDown.Value,{'Counts (baseline sub.)','Power (baseline sub.)','Power (baseline norm.)'}));
            app.UpdateAll;
        end

        % Button pushed function: refreshFileListButton
        function refreshFileListButtonPressed(app, event)
            app.UpdateFileList;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, false)
            app.fileList = uilistbox(app.UIFigure);
            app.fileList.Items = {};
            app.fileList.ValueChangedFcn = createCallbackFcn(app, @newFileSelected, true);
            app.fileList.Value = {};
            app.combIm = uiaxes(app.UIFigure);
            app.Panel_2 = uipanel(app.UIFigure);
            app.normalizeButton = uibutton(app.Panel_2, 'state');
            app.normalizeButton.ValueChangedFcn = createCallbackFcn(app, @normalizeButtonPressed, true);
            app.normalizeButton.Text = 'Normalize';
            app.combImLinesButton = uibutton(app.Panel_2, 'state');
            app.combImLinesButton.ValueChangedFcn = createCallbackFcn(app, @combImLinesButtonPressed, true);
            app.combImLinesButton.Text = 'Lines';
            app.combImLinesButton.Value = true;
            app.clim = uislider(app.Panel_2);
            app.clim.Limits = [0.001 1];
            app.clim.MajorTicks = [];
            app.clim.MajorTickLabels = {};
            app.clim.ValueChangingFcn = createCallbackFcn(app, @climSliderChanging, true);
            app.clim.MinorTicks = [];
            app.clim.Value = 1;
            app.ElectrodeSpinnerLabel = uilabel(app.Panel_2);
            app.ElectrodeSpinnerLabel.Text = 'Electrode';
            app.electrodeSpinner = uispinner(app.Panel_2);
            app.electrodeSpinner.ValueChangedFcn = createCallbackFcn(app, @newElecSelected, true);
            app.electrodeSpinner.Limits = [1 32];
            app.electrodeSpinner.Value = 1;
            app.ChannelEditFieldLabel = uilabel(app.Panel_2);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.channelLabel = uieditfield(app.Panel_2, 'numeric');
            app.channelLabel.Limits = [1 32];
            app.channelLabel.Editable = 'off';
            app.channelLabel.Value = 1;
            app.SFSpinnerLabel = uilabel(app.Panel_2);
            app.SFSpinnerLabel.Text = 'SF';
            app.sfSpinner = uispinner(app.Panel_2);
            app.sfSpinner.ValueChangedFcn = createCallbackFcn(app, @newSfSelected, true);
            app.sfSpinner.Limits = [1 14];
            app.sfSpinner.Value = 1;
            app.DIRSpinnerLabel = uilabel(app.Panel_2);
            app.DIRSpinnerLabel.Text = 'DIR';
            app.oriSpinner = uispinner(app.Panel_2);
            app.oriSpinner.ValueChangedFcn = createCallbackFcn(app, @newOriSelected, true);
            app.oriSpinner.Limits = [1 24];
            app.oriSpinner.Value = 1;
            app.cpdLabel = uilabel(app.Panel_2);
            app.cpdLabel.Text = 'cpd';
            app.degLabel = uilabel(app.Panel_2);
            app.degLabel.Text = 'deg';
            app.UnitsDropDownLabel = uilabel(app.Panel_2);
            app.UnitsDropDownLabel.Text = 'Units';
            app.unitsDropDown = uidropdown(app.Panel_2);
            app.unitsDropDown.Items = {'Counts (baseline sub.)', 'Power (baseline sub.)', 'Power (baseline norm.)'};
            app.unitsDropDown.ValueChangedFcn = createCallbackFcn(app, @newUnitsSelected, true);
            app.unitsDropDown.Value = 'Counts (baseline sub.)';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.AllelecsTab = uitab(app.TabGroup2);
            app.AllelecsTab.Title = 'All elecs';
            app.AllelecsTab.Units = 'pixels';
            app.oriMargIm = uiaxes(app.AllelecsTab);
            xlabel(app.oriMargIm, 'dir');
            ylabel(app.oriMargIm, 'elec');
            app.sfMargIm = uiaxes(app.AllelecsTab);
            xlabel(app.sfMargIm, 'sf');
            ylabel(app.sfMargIm, 'elec');
            app.SingleelecTab = uitab(app.TabGroup2);
            app.SingleelecTab.Title = 'Single elec';
            app.SingleelecTab.Units = 'pixels';
            app.singIm = uiaxes(app.SingleelecTab);
            xlabel(app.singIm, 'sf');
            ylabel(app.singIm, 'dir');
            app.singSf = uiaxes(app.SingleelecTab);
            xlabel(app.singSf, 'sf');
            ylabel(app.singSf, 'ips');
            app.singOri = uiaxes(app.SingleelecTab);
            xlabel(app.singOri, 'dir');
            ylabel(app.singOri, 'ips');
            app.refreshFileListButton = uibutton(app.UIFigure, 'push');
            app.refreshFileListButton.ButtonPushedFcn = createCallbackFcn(app, @refreshFileListButtonPressed, true);
            app.refreshFileListButton.Text = 'Refresh';
        end
    end

    methods (Access = public)

        % Construct app
        function app = JointTuneViewer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end